classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ApplicationMEFen1DUIFigure    matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        MaillageTab                   matlab.ui.container.Tab
        UIAxes                        matlab.ui.control.UIAxes
        SolutionTab                   matlab.ui.container.Tab
        UIAxes_2                      matlab.ui.control.UIAxes
        ComparaisonsolutionsTab       matlab.ui.container.Tab
        UIAxes_3                      matlab.ui.control.UIAxes
        AnalysederreurTab             matlab.ui.container.Tab
        UIAxes_4                      matlab.ui.control.UIAxes
        ErreurrelativeTab             matlab.ui.container.Tab
        UIAxes_5                      matlab.ui.control.UIAxes
        LogTab                        matlab.ui.container.Tab
        TextArea                      matlab.ui.control.TextArea
        ApplicationMEFen1DLabel       matlab.ui.control.Label
        MethodeButtonGroup            matlab.ui.container.ButtonGroup
        P1Button                      matlab.ui.control.RadioButton
        P2Button                      matlab.ui.control.RadioButton
        EvaluerButton                 matlab.ui.control.StateButton
        StatutLampLabel               matlab.ui.control.Label
        StatutLamp                    matlab.ui.control.Lamp
        FonctionSecondMembreFormatMatlabEditFieldLabel  matlab.ui.control.Label
        FonctionSecondMembreFormatMatlabEditField  matlab.ui.control.EditField
        EditField                     matlab.ui.control.NumericEditField
        TimerLabel                    matlab.ui.control.Label
        DomaineEditFieldLabel         matlab.ui.control.Label
        DomaineEditField              matlab.ui.control.EditField
        DomaineEditField_2            matlab.ui.control.EditField
        PasEditFieldLabel             matlab.ui.control.Label
        PasEditField                  matlab.ui.control.EditField
        AlphaEditFieldLabel           matlab.ui.control.Label
        AlphaEditField                matlab.ui.control.EditField
        BetaEditFieldLabel            matlab.ui.control.Label
        BetaEditField                 matlab.ui.control.EditField
        AnalysederreurCheckBox        matlab.ui.control.CheckBox
        SolutionexacteEditFieldLabel  matlab.ui.control.Label
        SolutionexacteEditField       matlab.ui.control.EditField
        RalisparCharafRezraziAyoubOutaghat2017ENSAMCasablancav13Label  matlab.ui.control.Label
        ExportationButtonGroup        matlab.ui.container.ButtonGroup
        ToutButton                    matlab.ui.control.ToggleButton
        RienButton                    matlab.ui.control.ToggleButton
    end

    
    methods (Access = public)
        %---------------------------
        % Fonction : triggerProgress
        % /
        % Changer la couleur de la lamp statut
        % 0 : Bon
        % 1 : En cours
        % ~ : Erreur
        %---------------------------
        function triggerProgress(app,status)
            if status == 0
                app.StatutLamp.Color = 'green';
            elseif status == 1
                app.StatutLamp.Color = 'yellow';
            else
                app.StatutLamp.Color = 'red';
            end
        end
        
        %---------------------------
        % Fonction : errorCompute
        % /
        % Evaluer l'erreur et l'erreur relative
        % errorEval : Erreur évaluée
        % errorEvalRel : Erreur relative
        % UE : Solution exacte
        %---------------------------
        function [errorEval, errorEvalRel, UE] = errorCompute(app,U,X)
            UE = str2func(app.SolutionexacteEditField.Value);
            errorEval = zeros(size(X,1),1);
            errorEvalRel = zeros(size(X,1),1);
            for i=1:size(X,1)
                errorEval(i) = U(i)-UE(i);
                errorEvalRel(i) = errorEval(i)/abs(UE(i));
            end
        end
        
        %---------------------------
        % Fonction : errorPlot
        % /
        % Générer les graphiques représentants les erreurs
        %---------------------------
        function [errorEval, errorEvalRel, UE] = errorPlot(app,U,X)
            logStep(app,'[ERRO] Evaluation erreur en cours...');
            [errorEval, errorEvalRel, UE] = errorCompute(app,U,X);
            logStep(app,'[OK] Erreur évaluée;');
            logStep(app,'[PLOT] Génération des graphiques erreurs...');
            cla(app.UIAxes_4,'reset');
            cla(app.UIAxes_5,'reset');
            plot(app.UIAxes_4,X,errorEval, 'r-o');
            plot(app.UIAxes_5,X,errorEvalRel, 'b-x');
            cla(app.UIAxes_3,'reset');
            fplot(app.UIAxes_3,UE, [min(X), max(X)], 'b--o');
            hold(app.UIAxes_3);
            plot(app.UIAxes_3,X,U, 'r--x');
            logStep(app,'[OK] Graphiques générés;');
        end
        
        %---------------------------
        % Fonction : computeP1
        % /
        % Evaluer la solution du problème avec une approximation P1
        %---------------------------
        function computeP1(app)
            cla(app.UIAxes,'reset');
            cla(app.UIAxes_2,'reset');
            
            % Domaine et pas
            a = str2num(app.DomaineEditField.Value);
            b = str2num(app.DomaineEditField_2.Value);
            h = str2num(app.PasEditField.Value);
            
            % Génération du maillage
            logStep(app,'[MESH] Maillage en cours de génération...');
            [X,T] = MaillageP1(app,a,b,h);
            logStep(app,'[OK] Maillage généré;');
            
            % Coefficients et second membre
            alpha = str2double(app.AlphaEditField.Value);
            beta = str2double(app.BetaEditField.Value);
            f = str2func(app.FonctionSecondMembreFormatMatlabEditField.Value);
            
            % Evaluation de la solution et graphiques solution/erreur
            logStep(app,'[EVAL] Résolution en cours...');
            [U] = EF_P1(app,alpha,beta,f,a,b,h);
            logStep(app,'[OK] Résolution terminée;');
            logStep(app,'[PLOT] Génération des graphiques...');
            plot(app.UIAxes_2,X,U, 'r.-');
            logStep(app,'[OK] Génération terminée;');
            errorEval = zeros(1);
            errorEvalRel = zeros(1);
            UE = @(x)0;
            if app.AnalysederreurCheckBox.Value == true
                logStep(app,'[ERRO] Solution exacte existante;');
                [errorEval, errorEvalRel, UE] = errorPlot(app,U,X);
            end
            exportInput = [a;b;h;string(func2str(f))];
            exportData(app,exportInput,X,T,U,errorEval,errorEvalRel,string(func2str(UE)));
        end
        
        %---------------------------
        % Fonction : computeP2
        % /
        % Evaluer la solution du problème avec une approximation P2
        %---------------------------
        function computeP2(app)
            cla(app.UIAxes,'reset');
            cla(app.UIAxes_2,'reset');
            
            % Domaine et pas
            a = str2num(app.DomaineEditField.Value);
            b = str2num(app.DomaineEditField_2.Value);
            h = str2num(app.PasEditField.Value);
            
            % Génération du maillage
            logStep(app,'[MESH] Maillage en cours de génération...');
            [X,T] = MaillageP2(app,a,b,h);
            logStep(app,'[OK] Maillage généré;');
            
            % Coefficients et second membre
            alpha = str2double(app.AlphaEditField.Value);
            beta = str2double(app.BetaEditField.Value);
            f = str2func(app.FonctionSecondMembreFormatMatlabEditField.Value);
            
            % Evaluation de la solution et graphiques solution/erreur
            logStep(app,'[EVAL] Résolution en cours...');
            [U] = EF_P2(app,alpha,beta,f,a,b,h);
            logStep(app,'[OK] Résolution terminée;');
            logStep(app,'[PLOT] Génération des graphiques...');
            plot(app.UIAxes_2,X,U, 'r.-');
            logStep(app,'[OK] Génération terminée;');
            errorEval = zeros(1);
            errorEvalRel = zeros(1);
            UE = @(x)0;
            if app.AnalysederreurCheckBox.Value == true
                logStep(app,'[ERRO] Solution exacte existante;');
                [errorEval, errorEvalRel, UE] = errorPlot(app,U,X);
            end
            exportInput = [a;b;h;string(func2str(f))];
            exportData(app,exportInput,X,T,U,errorEval,errorEvalRel,string(func2str(UE)));
        end
        
        %---------------------------
        % Fonction : shouldExport
        % /
        % Déterminer si l'utilisateur veut exporter les données
        %---------------------------
        function r = shouldExport(app)
            r = true;
            if app.RienButton.Value == true
                r = false;
            end
        end
        
        %---------------------------
        % Fonction : exportData
        % /
        % Exporter les données sous format Excel (xslx)
        %---------------------------
        function exportData(app,exportInput,X,T,U,errorEval,errorEvalRel,UE)
            dirname = 'exports/';
            mkdir(dirname);
            if(shouldExport(app))
                logStep(app,'[SAVE] Exportation débutée...');
                if app.ToutButton.Value == true
                    xlsfile = string(strcat('exports/export_data_',string(now),'.xlsx'));
                    xlswrite(xlsfile,exportInput,'Domaine et second membre');
                    xlswrite(xlsfile,X,'Noeuds');
                    xlswrite(xlsfile,T,'Eléments');
                    xlswrite(xlsfile,U,'Solution évaluée');
                    xlswrite(xlsfile,UE,'Solution exacte');
                    xlswrite(xlsfile,errorEval,'Erreur évaluée');
                    xlswrite(xlsfile,errorEvalRel,'Erreur relative');
                end
                logStep(app,'[SAVE] Exportation terminée;');
            end
        end
        
        %---------------------------
        % Fonction : logStep
        % /
        % Garder un suivi des étapes en cours
        %---------------------------
        function logStep(app,txt)
            old = app.TextArea.Value;
            current = sprintf('%s\n%s\n',string(old),txt);
            app.TextArea.Value = current;
        end
    end
    

    methods (Access = private)

        % Value changed function: EvaluerButton
        function Evaluer(app, event)
            %---------------------------
            % Fonction : Evaluer
            % /
            % Fonction déclanchée au clic du boutton Evaluer
            %---------------------------
            logStep(app,'[INIT] Evaluation lancée;');
            tic
            triggerProgress(app,1);
            if app.P1Button.Value == true
                logStep(app,'[EVAL] Approximation P1 choisie;');
                computeP1(app);
            elseif app.P2Button.Value == true
                logStep(app,'[EVAL] Approximation P2 choisie;');
                computeP2(app);
            end
            triggerProgress(app,0);
            logStep(app,'[OK] Programme terminé avec succès;');
            toc
            logStep(app,strcat('[TIME] Opération effectuée en __',num2str(toc),'s'));
            logStep(app,'[END] ----------------------------------');
            app.EditField.Value = toc;
        end

        % Value changed function: AnalysederreurCheckBox
        function AnalysederreurCheckBoxValueChanged(app, event)
            %---------------------------
            % Fonction : AnalysederreurCheckBoxValueChanged
            % /
            % Fonction déclanchée au changement de l'état de la case Analyse d'erreur
            %---------------------------
            value = app.AnalysederreurCheckBox.Value;
            if value == true
                app.SolutionexacteEditField.Editable = 'on';
                app.SolutionexacteEditField.Value = '@(x)sin(x)';
                app.SolutionexacteEditField.Enable = 'on';
                app.SolutionexacteEditFieldLabel.Enable = 'on';
            else
                app.SolutionexacteEditField.Editable = 'off';
                app.SolutionexacteEditField.Value = '';
                app.SolutionexacteEditField.Enable = 'off';
                app.SolutionexacteEditFieldLabel.Enable = 'off';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ApplicationMEFen1DUIFigure = uifigure;
            app.ApplicationMEFen1DUIFigure.Name = 'Application MEF en 1D';
            app.TabGroup = uitabgroup(app.ApplicationMEFen1DUIFigure);
            app.MaillageTab = uitab(app.TabGroup);
            app.MaillageTab.Title = 'Maillage';
            app.UIAxes = uiaxes(app.MaillageTab);
            title(app.UIAxes, 'Maillage')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.SolutionTab = uitab(app.TabGroup);
            app.SolutionTab.Title = 'Solution';
            app.UIAxes_2 = uiaxes(app.SolutionTab);
            title(app.UIAxes_2, 'Solution')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.ComparaisonsolutionsTab = uitab(app.TabGroup);
            app.ComparaisonsolutionsTab.Title = 'Comparaison solutions';
            app.UIAxes_3 = uiaxes(app.ComparaisonsolutionsTab);
            title(app.UIAxes_3, 'Comparaison solutions')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            app.AnalysederreurTab = uitab(app.TabGroup);
            app.AnalysederreurTab.Title = 'Analyse d''erreur';
            app.UIAxes_4 = uiaxes(app.AnalysederreurTab);
            title(app.UIAxes_4, 'Analyse d''erreur')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            app.ErreurrelativeTab = uitab(app.TabGroup);
            app.ErreurrelativeTab.Title = 'Erreur relative';
            app.UIAxes_5 = uiaxes(app.ErreurrelativeTab);
            title(app.UIAxes_5, 'Erreur relative')
            xlabel(app.UIAxes_5, 'X')
            ylabel(app.UIAxes_5, 'Y')
            app.LogTab = uitab(app.TabGroup);
            app.LogTab.Title = 'Log';
            app.TextArea = uitextarea(app.LogTab);
            app.TextArea.Editable = 'off';
            app.ApplicationMEFen1DLabel = uilabel(app.ApplicationMEFen1DUIFigure);
            app.ApplicationMEFen1DLabel.Text = 'Application MEF en 1D';
            app.MethodeButtonGroup = uibuttongroup(app.ApplicationMEFen1DUIFigure);
            app.MethodeButtonGroup.Title = 'Methode';
            app.P1Button = uiradiobutton(app.MethodeButtonGroup);
            app.P1Button.Text = 'P1';
            app.P1Button.Value = true;
            app.P2Button = uiradiobutton(app.MethodeButtonGroup);
            app.P2Button.Text = 'P2';
            app.EvaluerButton = uibutton(app.ApplicationMEFen1DUIFigure, 'state');
            app.EvaluerButton.ValueChangedFcn = createCallbackFcn(app, @Evaluer, true);
            app.EvaluerButton.Text = 'Evaluer';
            app.StatutLampLabel = uilabel(app.ApplicationMEFen1DUIFigure);
            app.StatutLampLabel.Text = 'Statut ';
            app.StatutLamp = uilamp(app.ApplicationMEFen1DUIFigure);
            app.FonctionSecondMembreFormatMatlabEditFieldLabel = uilabel(app.ApplicationMEFen1DUIFigure);
            app.FonctionSecondMembreFormatMatlabEditFieldLabel.Text = 'Fonction Second Membre (Format Matlab)';
            app.FonctionSecondMembreFormatMatlabEditField = uieditfield(app.ApplicationMEFen1DUIFigure, 'text');
            app.FonctionSecondMembreFormatMatlabEditField.Value = '@(x)sin(x)';
            app.EditField = uieditfield(app.ApplicationMEFen1DUIFigure, 'numeric');
            app.EditField.Editable = 'off';
            app.EditField.Enable = 'off';
            app.TimerLabel = uilabel(app.ApplicationMEFen1DUIFigure);
            app.TimerLabel.Text = 'Timer';
            app.DomaineEditFieldLabel = uilabel(app.ApplicationMEFen1DUIFigure);
            app.DomaineEditFieldLabel.Text = 'Domaine';
            app.DomaineEditField = uieditfield(app.ApplicationMEFen1DUIFigure, 'text');
            app.DomaineEditField.Value = '0';
            app.DomaineEditField_2 = uieditfield(app.ApplicationMEFen1DUIFigure, 'text');
            app.DomaineEditField_2.Value = 'pi';
            app.PasEditFieldLabel = uilabel(app.ApplicationMEFen1DUIFigure);
            app.PasEditFieldLabel.Text = 'Pas';
            app.PasEditField = uieditfield(app.ApplicationMEFen1DUIFigure, 'text');
            app.PasEditField.Value = '0.1';
            app.AlphaEditFieldLabel = uilabel(app.ApplicationMEFen1DUIFigure);
            app.AlphaEditFieldLabel.Text = 'Alpha';
            app.AlphaEditField = uieditfield(app.ApplicationMEFen1DUIFigure, 'text');
            app.AlphaEditField.Value = '1';
            app.BetaEditFieldLabel = uilabel(app.ApplicationMEFen1DUIFigure);
            app.BetaEditFieldLabel.Text = 'Beta';
            app.BetaEditField = uieditfield(app.ApplicationMEFen1DUIFigure, 'text');
            app.BetaEditField.Value = '2';
            app.AnalysederreurCheckBox = uicheckbox(app.ApplicationMEFen1DUIFigure);
            app.AnalysederreurCheckBox.ValueChangedFcn = createCallbackFcn(app, @AnalysederreurCheckBoxValueChanged, true);
            app.AnalysederreurCheckBox.Text = 'Analyse d''erreur';
            app.SolutionexacteEditFieldLabel = uilabel(app.ApplicationMEFen1DUIFigure);
            app.SolutionexacteEditFieldLabel.Enable = 'off';
            app.SolutionexacteEditFieldLabel.Text = 'Solution exacte';
            app.SolutionexacteEditField = uieditfield(app.ApplicationMEFen1DUIFigure, 'text');
            app.SolutionexacteEditField.Editable = 'off';
            app.SolutionexacteEditField.Enable = 'off';
            app.RalisparCharafRezraziAyoubOutaghat2017ENSAMCasablancav13Label = uilabel(app.ApplicationMEFen1DUIFigure);
            app.RalisparCharafRezraziAyoubOutaghat2017ENSAMCasablancav13Label.Text = 'Réalisé par Charaf Rezrazi & Ayoub Outaghat - 2017 ENSAM Casablanca - v1.3';
            app.ExportationButtonGroup = uibuttongroup(app.ApplicationMEFen1DUIFigure);
            app.ExportationButtonGroup.Title = 'Exportation';
            app.ToutButton = uitogglebutton(app.ExportationButtonGroup);
            app.ToutButton.Text = 'Tout';
            app.ToutButton.Value = true;
            app.RienButton = uitogglebutton(app.ExportationButtonGroup);
            app.RienButton.Text = 'Rien';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ApplicationMEFen1DUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ApplicationMEFen1DUIFigure)
        end
    end
end